classdef gait_analysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        BluetoothConnectionPanel  matlab.ui.container.Panel
        StatusLamp                matlab.ui.control.Lamp
        StatusLabel               matlab.ui.control.Label
        DisconnectButton          matlab.ui.control.Button
        ConnectButton             matlab.ui.control.Button
        DeviceListDropDown        matlab.ui.control.DropDown
        DeviceListDropDownLabel   matlab.ui.control.Label
        ScanButton                matlab.ui.control.Button
    end

    
    properties (Access = private)
        BLEDevice % ble object
        DeviceTable % result of blelist command
        IsConnected = false
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ScanButton
        function ScanButtonPushed(app, event)
            app.StatusLabel.Text = "Scanning...";
            app.StatusLamp.Color = [1 1 0]; % yellow color while scanning

            app.DeviceTable = blelist; % scans for nearby ble devices

            % the case where no BLE devices are found
            if isempty(app.DeviceTable)
                app.StatusLabel.Text = "No BLE devices found";
                app.StatusLamp.Color = [1 0 0]; % red color
                return;
            end

            % Populate dropdown with device names
            names = app.DeviceTable.Name; % the name of the devices from the blelist command
            names(cellfun(@isempty, names)) = {"Unknown"}; % replaces empty device names with "Unknown"
            app.DeviceListDropDown.Items = names; % populate the dropdown
            app.StatusLabel.Text = "Scan complete";
            app.StatusLamp.Color = [1 1 0]; % yellow color

        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            % if we haven't populated the dropdown
            if isempty(app.DeviceListDropDown.Value)
                return;
            end

            deviceName = app.DeviceListDropDown.Value; % grabs the name of the currently selected device in the dropdown

            try
                app.BLEDevice = ble(deviceName); % searches for a BLE peripheral with the given name and establishes a connection
                app.IsConnected = true; % if no issues, mark it as connected
                
                % show that it is connected in the UI
                app.StatusLabel.Text = "Connected to " + deviceName;
                app.StatusLamp.Color = [0 1 0]; % green
        
            catch ME % if any error occurs inside the try block, jump here
                % show that it is NOT connected in the UI
                app.StatusLabel.Text = "Connection failed";
                app.StatusLamp.Color = [1 0 0];

                disp(ME.message); % prints the specific error message to the MATLAB command window
            end
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            % if it is connected
            if app.IsConnected
                clear app.BLEDevice % remove the BLE device object from memory
                app.IsConnected = false; % mark it as disconnected

                % show that it is NOT connected in the UI
                app.StatusLabel.Text = "Disconnected";
                app.StatusLamp.Color = [1 0 0];
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BluetoothConnectionPanel = uipanel(app.UIFigure);
            app.BluetoothConnectionPanel.Title = 'Bluetooth Connection';
            app.ScanButton = uibutton(app.BluetoothConnectionPanel, 'push');
            app.ScanButton.ButtonPushedFcn = createCallbackFcn(app, @ScanButtonPushed, true);
            app.ScanButton.Text = 'Scan';
            app.DeviceListDropDownLabel = uilabel(app.BluetoothConnectionPanel);
            app.DeviceListDropDownLabel.Text = 'Device List';
            app.DeviceListDropDown = uidropdown(app.BluetoothConnectionPanel);
            app.ConnectButton = uibutton(app.BluetoothConnectionPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.DisconnectButton = uibutton(app.BluetoothConnectionPanel, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.StatusLabel = uilabel(app.BluetoothConnectionPanel);
            app.StatusLabel.Text = {'Status: '; 'Disconnected'};
            app.StatusLamp = uilamp(app.BluetoothConnectionPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gait_analysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end